classdef swordFighting_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        J1Slider                   matlab.ui.control.Slider
        J1SliderLabel              matlab.ui.control.Label
        J6Slider_2                 matlab.ui.control.Slider
        J6Slider_2Label            matlab.ui.control.Label
        J5Slider_2                 matlab.ui.control.Slider
        J5Slider_2Label            matlab.ui.control.Label
        J4Slider_2                 matlab.ui.control.Slider
        J4Slider_2Label            matlab.ui.control.Label
        J3Slider_2                 matlab.ui.control.Slider
        J3Slider_2Label            matlab.ui.control.Label
        J2Slider_2                 matlab.ui.control.Slider
        J2Slider_2Label            matlab.ui.control.Label
        J1Slider_2                 matlab.ui.control.Slider
        J1Slider_2Label            matlab.ui.control.Label
        J7Slider                   matlab.ui.control.Slider
        J7SliderLabel              matlab.ui.control.Label
        J6Slider                   matlab.ui.control.Slider
        J6SliderLabel              matlab.ui.control.Label
        J5Slider                   matlab.ui.control.Slider
        J5SliderLabel              matlab.ui.control.Label
        J4Slider                   matlab.ui.control.Slider
        J4SliderLabel              matlab.ui.control.Label
        J3Slider                   matlab.ui.control.Slider
        J3SliderLabel              matlab.ui.control.Label
        J2Slider                   matlab.ui.control.Slider
        J2SliderLabel              matlab.ui.control.Label
        Player1TextAreaLabel_2     matlab.ui.control.Label
        NopositionselectedLabel_2  matlab.ui.control.Label
        NopositionselectedLabel    matlab.ui.control.Label
        FightButton_2              matlab.ui.control.Button
        Player1_pos4               matlab.ui.control.Button
        Player2_pos4               matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        WinLabel                   matlab.ui.control.Label
        ResetButton_2              matlab.ui.control.Button
        Player1TextAreaLabel       matlab.ui.control.Label
        Player2TextAreaLabel       matlab.ui.control.Label
        Player2_pos3               matlab.ui.control.Button
        Player2_pos2               matlab.ui.control.Button
        Player2_pos1               matlab.ui.control.Button
        Player1_pos3               matlab.ui.control.Button
        Player1_pos2               matlab.ui.control.Button
        Player1_pos1               matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    properties (Access = public)
       % Properties
        robots = cell(1,2);
        grip_ = cell(1,2);
        grip_1;
        grip_2;
        
        swordfile = {"model3D/Lightsaber2.ply", "model3D/Lightsaber2.ply"};
        baseRobot = {transl(0,0,0.5), transl(0,1,0.5)};
        
        swordStartLoc = {[-0.4 -0.5 0.5], [-0.4 1.55 0.5]};
        
        steps = 25;

        % setup each link
        links = [];

        % Setup robot pose
        pose = cell(1,2);
        pose1 = {transl([0 1 1]) * troty(0, 'deg'), transl([0 0 1]) * trotz(0, 'deg')};
        pose2 = cell(1,2);
        poses = 4;
        robot_1_home = [0,0,0,0,0,0,0];
        robot_2_home = [0,0,0,0,0,0];

        robot_1_q = [];
        robot_2_q = [];

        winner = 2;

        % Setup Swords
        swords = cell(1,2);
        sword_vertices = cell(1,2);

        pointsOnPlane = [-2,-2,0;
                         -2,3.5,0;
                         2,3.5,0;
                         2,-2,0];
        planeNormals = [0,-1,0;
                        -1,0,0;
                        0,-1,0;
                        0,-1,0];
        
        
        %% ****************************** Prepare to fight pose need to be hard code but it need to be adjust later on *********************************************
        Preparepose = {[-0.4 0 0 0 0 0 -pi/2], [-pi/2 pi/2 1.3 0 0 0.26]};
        Testose = {[-0.4 0 0 0 0 0 -0.5], deg2rad([-130 61 60 0 6 90])};

        UR5_poses = [-0.4 0 0 0 0 0 -0.5;   
                    -0.4 -0.5 0 pi/3 1 0 0;
                    -0.4559 -0.5775 -1.2918 -0.1777 2.6849 -1.5708 2.1482;
                    0 0.2797 -0.3640 0 -1.1375 1.5761 -1.8507];
        % Preparepose{1} = [-0.4 0 0 0 0 0 -pi/2];
        % Preparepose{2} = [-pi/2 pi/2 -0 pi/3 0 0.5];
        
        % Object in the scence that are 3D models
        ObjectInTheScene=[];
        
        Fanuc_poses = [-2.2689 1.0647 1.0472 0 0.1047 1.5708;
                        -1.5708 2.1906 2.2471 0.7330 0.8378 1.5708;
                        -pi/4 pi/2.5 pi/2.2 pi/2 0 0;
                        2.3562 2.2048 2.1457 0.7330 0.8378 2.3562];
        
        gripper = zeros(2,3);
        Grip1;
        Grip2;
        GripDegree = 45;

        TurnOnGripper = false;          
    end


    methods (Access = public)
        function Execution(app)
            % pickup the sword
            
            [app.gripper,collision] = app.pickupSwordsStep(app.robots, app.PickupPose, app.swords, app.sword_vertices, app.steps);
            % Prepare the to fight 
            [app.gripper,collision] = app.MoveRobotToLocation(app.robots, app.Preparepose, app.swords, app.sword_vertices, app.steps, false);
            % Start random pose to fighting
            for i = 1:length(app.DemoPose)
                Pose = app.DemoPose{i};
                [app.gripper,collision] = app.MoveRobotToLocation(app.robots, Pose, app.swords, app.sword_vertices, app.steps);
                [app.gripper,collision] = app.MoveRobotToLocation(app.robots, app.Preparepose, app.swords, app.sword_vertices, app.steps, false);
            end
           
        end

        %% Setup Robot parts and robot base
        function robot = SetupRobot(app, base, newRobot)
            if newRobot
                robot = FanucM20(base); 
            else
                robot = LinearUR5(base);
            end
            robot.model.delay = 0.01;
            q = zeros(1, length(robot.model.links));
            robot.model.animate(q);
        end

        %% Setup Environment of the scene 
        function Object = SetupEnvironment(app)
            hold on;
            app.PlaceFloor(20, 'Images/floor.jpg');
            app.PlaceWall(0,6,4,15,'Images/KendoClub1.jpg', true);
            app.PlaceWall(6,0,4,15,'Images/KendoClub1.jpg', false);
            
            Object{1} = PlaceObject('model3D/table_v1.ply', [-0.4,0,0]); % Assuming PlaceObject is a function or another script
            Object{2} = PlaceObject('model3D/table_v1.ply', [-0.4,1,0]);
            PlaceObject('model3D/C3PO.ply', [4,-2,0]);
            PlaceObject('model3D/C3PO.ply', [4,-1,0]);
            PlaceObject('model3D/C3PO.ply', [4,0,0]);
            PlaceObject('model3D/C3PO.ply', [4,1,0]);
            PlaceObject('model3D/C3PO.ply', [4,2,0]);
            PlaceObject('model3D/Star_wars_JAWA.ply', [-2,5,0]);
            PlaceObject('model3D/Star_wars_JAWA.ply', [-1,5,0]);
            PlaceObject('model3D/Star_wars_JAWA.ply', [0,5,0]);
            PlaceObject('model3D/Star_wars_JAWA.ply', [1,5,0]);
            PlaceObject('model3D/Star_wars_JAWA.ply', [2,5,0]);
            PlaceObject('model3D/TABLER~1.PLY', [0,4,0]);
            PlaceObject('model3D/fireExtinguisher.ply', [1,4,0]);
            PlaceObject('model3D/emergencyStopButton.ply', [-0.2,4,0.25]);
            PlaceObject('model3D/Traffic Cone.ply', [-2,3.5,0]);
            PlaceObject('model3D/Traffic Cone.ply', [2,3.5,0]);
            PlaceObject('model3D/Traffic Cone.ply', [-2,-2,0]);
            PlaceObject('model3D/Traffic Cone.ply', [2,-2,0]);

            view(3);
            axis([-3.5 5 -3.5 6 -0.1 4]);
            % axis([-3 3 -3 3 0.1 4]);
            camlight;
        end
        function PlaceFloor(app, imageSize, img)
            img = imread(img);
            img = im2double(img);
            % Define the size and position of the image
            imageSize = imageSize;  % Size of the image (5x5 units)
            [rows, cols] = size(img);
            scaleFactorX = imageSize / cols;
            scaleFactorY = imageSize / rows;
            
            % Create a scaled meshgrid
            [X, Y] = meshgrid(linspace(-imageSize/2, imageSize/2, cols), linspace(-imageSize/2, imageSize/2, rows));
            
            % Set Z to zero for a flat surface
            Z = zeros(size(X));
            
            % Plot the image
            surf(X, Y, Z, img, 'FaceColor', 'texturemap', 'EdgeColor', 'none');
        end
        function PlaceWall(app, x,y,height,wallWidth,img, rotate)
            % Read the image
            img = imread(img);
            img = imrotate(img, 180);
            img = im2double(img);  % Convert to double for texture mapping
            % Define the wall dimensions and position
            wallWidth = wallWidth;  % Width of the wall
            wallHeight = height; % Height of the wall
            wallPositionX = x; % X position of the wall
            wallPositionY = y; % Y position of the wall
            if rotate
                % Create a meshgrid for the wall
                [X, Z] = meshgrid(linspace(wallPositionX - wallWidth/2, wallPositionX + wallWidth/2, 100), linspace(0, wallHeight, 100));
                Y = wallPositionY * ones(size(X)); % Y is constant as the wall is vertical
            else
                % Create a meshgrid for the wall
                [Y, Z] = meshgrid(linspace(wallPositionY - wallWidth/2, wallPositionY + wallWidth/2, 100), linspace(0, wallHeight, 100));
                X = wallPositionX * ones(size(Y)); % Y is constant as the wall is vertical
            end
            % Plot the wall
            surf(X, Y, Z, img, 'FaceColor', 'texturemap', 'EdgeColor', 'none');  % Change 'blue' to any color you like
        end
        
        %% Plot bounding box
        function plotBoundingBox(app,vertices)
            minV = min(vertices);
            maxV = max(vertices);
            for i = 1:3
                range{i} = linspace(minV(i), maxV(i), 2);
            end
            [X, Y, Z] = meshgrid(range{:});
            X = X(:);
            Y = Y(:);
            Z = Z(:);
            plot3(X, Y, Z, 'ro'); % Plot corners of the bounding box
            hold on;
        end
        %% Setup Swords location base on hardcode location
        function [sword1, sword1_vertices, sword2, sword2_vertices] = setupSwords(app, swordfile1, swordfile2, sword1StartLoc, sword2StartLoc)
            sword1 = PlaceObject(swordfile1);
            sword2 = PlaceObject(swordfile2);

            sword1_vertices = get(sword1, 'Vertices');
            sword2_vertices = get(sword2, 'Vertices');
            app.RotateObject(sword1, (transl(sword1StartLoc) * trotx(90, 'deg')));
            app.RotateObject(sword2, (transl(sword2StartLoc) * trotx(90, 'deg')));
        end
      
        %% Update Swords location 
        function [sword_vertices] = UpdateObjectsVertices(app, objects)
            sword_vertices = cell(1,length(objects));
            for i=1:length(objects)
                sword_vertices{i} = get(objects{i}, 'Vertices');
            end
        end
       
        %% Check collision of swords
        function isColliding = checkObjectsCollision(app, vertices1, vertices2)
            % Calculate the bounding box for each set of vertices
            min1 = min(vertices1);
            max1 = max(vertices1);
            min2 = min(vertices2);
            max2 = max(vertices2);
        
            % Check for overlap in each dimension
            overlapX = (min1(1) < max2(1)) && (max1(1) > min2(1));
            overlapY = (min1(2) < max2(2)) && (max1(2) > min2(2));
            overlapZ = (min1(3) < max2(3)) && (max1(3) > min2(3));
        
            % If there is overlap in all three dimensions, the objects are colliding
            isColliding = overlapX && overlapY && overlapZ;
        end
       
        %% This function is initial function for the robot to pickup swords and return the pose to prepare pose
        function [gripper,collision] = pickupSwordsStep(app, robots, locations, swords, swordVertices, steps)
            [gripper,collision] = app.MoveRobotToLocation(robots, locations, [], swordVertices, steps, true);
            if app.TurnOnGripper
                gripper(1,:) = app.GridGripping(gripper(1,:), robots{1}, true);
                gripper(2,:) = app.GridGripping(gripper(2,:), robots{2}, true);
            end
            for i = 1:length(robots)
                robot = robots{i};
                sword = swords{i};
                vertices = swordVertices{i};
                app.MoveSwords(robot, sword, vertices);
                pause(0.001);
            end
            % app.gripper = app.DeleteGrip(app.gripper);
            % gripper = app.gripper;
        end

        function [app,collision] = PreparePoses(app, robots, locations, swords, swordVertices, steps)
            [app.gripper,collision] = MoveRobotToLocation(robots, locations, swords, swordVertices, steps, false);
        end

        %% Move the robot base on end-effector from 'location', update swords location and checking collision between robot parts itapp.
        function [gripper,collision] = MoveRobotToLocation(app, robots, locations, swords, swordVertices, steps, PassCollision)
            % GripOpen = false;
            collision = false;
            gripper = [];
            if nargin < 7			
                PassCollision = true;		
        		
            end

            qMatrix = cell(1, length(robots));
            
            for i = 1:length(robots)
                robot = robots{i};
                location = locations{i};
                qMatrix{i} = app.CalculateQmatrix(robot, location, steps);
            end
            
            for s = 1:steps
                for i = 1:length(robots)
                    robot = robots{i};
                    sword = [];
                    if ~isempty(swords)
                        sword = swords{i};
                    end                    
                    try 
                        robot.model.animate(qMatrix{i}(s,:)); 
                    catch 
                        % robot.model.animate(qMatrix{i}(s,:) - 0.1); 
                        disp('Unable to move in this matrix : ');
                        display(qMatrix{i}(s,:));
                    end
                    vertices = swordVertices{i};
                    app.MoveSwords(robot, sword, vertices);
                    pause(0.001);
                end
                if app.TurnOnGripper
                    app.gripper(1,:) = app.SetupGrip(app.gripper(1,:),app.robots{1});
                    app.gripper(2,:) = app.SetupGrip(app.gripper(2,:),app.robots{2});
                    gripper = app.gripper;
                end
                %% this may need to be in the CheckCollision ////////////////////////////////////////////////////////
                if PassCollision
                    [sword_vertices] = app.UpdateObjectsVertices(app.swords);
                    isColliding = app.checkObjectsCollision(sword_vertices{1}, sword_vertices{2});
                    if isColliding
                        collision = true;
                        disp('Swords collide')
                        return;
                    end
    
                    [collision,swordCollide] = app.CheckCollision(robots{1}, robots{2}, sword_vertices);
                    if collision
                        % if the robot part is collition stop the robot immediately
                        disp('STOP All systems, the robot parts have collide.')
                        return;
                    elseif swordCollide
                        disp('Swords collide with robot')
                        return;
                    end
                end
                %% ////////////////////////////////////////////////////////////////////////////////////////////////////////////////
            end
        end

        function qMatrix = CalculateQmatrix(app, robot, location, steps)
            % Ensure output is initialized
            qMatrix = [];
            if length(robot.model.links) ~= length(location)
                if length(location) == 3
                    MoveToObject = transl(location) * troty(-90,'deg');
                else
                    MoveToObject = location;
                end
                
                initialGuess = robot.model.getpos;
                try 
                    newQ1 = [];
                    newQ1 = robot.model.ikine(MoveToObject, 'q0', initialGuess, 'forceSoln');
                catch 
                    newQ1 = robot.model.ikcon(MoveToObject, initialGuess);
                end
                
                if isempty(newQ1)
                    newQ1 = robot.model.ikcon(MoveToObject, 'q0', initialGuess);
                end 
            else
                initialGuess = robot.model.getpos;
                newQ1 = location;
            end
            try 
                qMatrix = jtraj(initialGuess,newQ1,steps);
            end
        end

        
        function MoveSwords(app, robot, sword, swordVertices)
            if isempty(sword) 
                return;  
            end
            robotPose = robot.model.fkine(robot.model.getpos).T * trotz(90, 'deg');
            % transformedVertices = [swordVertices, ones(size(swordVertices,1),1)] * robotPose';
            % set(sword,'Vertices',transformedVertices(:,1:3));
            
            % Create a homogeneous transformation matrix for rotation
            rotationMatrix = robotPose;            
            % Get the object's vertices
            vertices = get(sword, 'Vertices');
            % Apply the rotation transformation to the object's vertices
            transformedVertices = (rotationMatrix * [swordVertices, ones(size(swordVertices, 1), 1)]')';
            % Update the object's vertices with the rotated vertices
            set(sword, 'Vertices', transformedVertices(:, 1:3));
        end

        function RotateObject(app, object, transformMatrix)
            vertices = get(object, 'Vertices');
            transformedVertices = (transformMatrix * [vertices, ones(size(vertices, 1), 1)]')';
            set(object, 'Vertices', transformedVertices(:, 1:3));
        end


        %% UpdateEachLink
        function tr = UpdateEachLink(app, robot)
            q = zeros(1,robot.model.n); 
            tr = zeros(4,4,robot.model.n+1);
            tr(:,:,1) = robot.model.base;
            L = robot.model.links;
            for i = 1 : robot.model.n
                tr(:,:,i+1) = tr(:,:,i) * trotz(q(i)+L(i).offset) * transl(0,0,L(i).d) * transl(L(i).a,0,0) * trotx(L(i).alpha);
            end
        end

        %% Setup ellipsoid
        function robots = SetupEllipsoid(app, robots)

            for i = 1:length(robots)
                robot = robots{i};
                
                numLinks = robot.model.n+1;
                for k = 2:numLinks  % Start from the second link
                    % Compute the distance between two consecutive transformation matrices
                    distanceFromXYZ = sqrt(robot.model.links(k-1).a^2 + robot.model.links(k-1).d^2);
                    
                    % Handle the case where distanceFromXYZ is 0
                    if distanceFromXYZ == 0
                        distanceFromXYZ = 0.1; % Set a minimum value or adjust as needed
                    end
                    
                    % Use the computed distance to determine the radii of the ellipsoid
                    radii = [distanceFromXYZ/1.2, 0.1, 0.1];  % Adjust as needed
                    center = [-distanceFromXYZ/2 0 0];


                    a = robot.model.links(k-1).a;
                    d = robot.model.links(k-1).d;
                    
                    % Compute the distance for ellipsoid size
                    distanceFromXYZ = sqrt(a^2 + d^2);
                    
                    % Handle the case where distanceFromXYZ is 0
                    if distanceFromXYZ == 0
                        distanceFromXYZ = 0.1; % Set a minimum value or adjust as needed
                    end
                    if a ~= 0
                        radii = [-distanceFromXYZ/2, 0.2, 0.2];
                        center = [-distanceFromXYZ/2, 0, 0];
                    else
                        radii = [0.2, -distanceFromXYZ/2, 0.2];
                        center = [0, -distanceFromXYZ/2, 0];
                    end

                    [X,Y,Z] = ellipsoid(center(1), center(2), center(3), radii(1), radii(2), radii(3));
                    robot.model.points{k} = [X(:),Y(:),Z(:)];
                    warning off
                    robot.model.faces{k} = delaunay(robot.model.points{k});
                    warning on;
                end
                robots{i} = robot;
            end
            % workspace_limits = [-2 3 -1 2 -0 3]; % Example limits, adjust as needed
            % robots{1}.model.plot3d(robots{1}.model.getpos, 'workspace', workspace_limits);
            % robots{2}.model.plot3d(robots{2}.model.getpos, 'workspace', workspace_limits);
            % axis(workspace_limits);
            % view(3)

        end

        function [points, radiis, ellipsoidCenters] = UpdateEllipsoid(app, robot, tr)
            links = robot.model.links;
            numLinks = robot.model.n + 1;
            points = cell(1, numLinks);
            radiis = cell(1, numLinks);
            ellipsoidCenters = cell(1, numLinks);
            sizeRadii = 0.05;
            h = cell(1, numLinks); % Cell array to store plot handles
        
            for i = 2:numLinks  % Start from the second link
                a = robot.model.links(i - 1).a;
                d = robot.model.links(i - 1).d;
        
                % Compute the distance for ellipsoid size
                distanceFromXYZ = sqrt(a^2 + d^2);
        
                % Handle the case where distanceFromXYZ is 0
                if distanceFromXYZ == 0
                    distanceFromXYZ = 0.1; % Set a minimum value or adjust as needed
                end
        
                if a ~= 0
                    radii = [-distanceFromXYZ / 2, sizeRadii, sizeRadii];
                    ellipsoidCenter = [-distanceFromXYZ / 2, 0, 0];
                else
                    radii = [sizeRadii, -distanceFromXYZ / 2, sizeRadii];
                    ellipsoidCenter = [0, -distanceFromXYZ / 2, 0];
                end        
                radiis{i} = radii;
                ellipsoidCenters{i} = ellipsoidCenter;
        
                % Extract the rotation matrix and translation vector for the current link
                rotation = tr(1:3, 1:3, i);
                translation = tr(1:3, 4, i);
        
                % Create the ellipsoid in its local frame
                [X, Y, Z] = ellipsoid(ellipsoidCenter(1), ellipsoidCenter(2), ellipsoidCenter(3), radii(1), radii(2), radii(3));
        
                % Rotate and translate the ellipsoid points
                rotatedPoints = rotation * [X(:)'; Y(:)'; Z(:)'];
                translatedPoints = bsxfun(@plus, rotatedPoints, translation);
        
                % Store the points
                points{i} = translatedPoints';
        
                % Visualize the ellipsoid
                % h{i} = surf(reshape(translatedPoints(1, :), size(X)), ...
                %             reshape(translatedPoints(2, :), size(Y)), ...
                %             reshape(translatedPoints(3, :), size(Z)));
            end
        end

        %% GetLinkPoses
        % q - robot joint angles
        % robot -  seriallink robot model
        % transforms - list of transforms
        function [ transforms ] = GetLinkPoses(app, q, robot)
            links = robot.links;
            transforms = zeros(4, 4, length(links) + 1);
            transforms(:,:,1) = robot.base;
            for i = 1:length(links)
                L = links(1,i);
                if L.jointtype == 'P' %Prismatic
                    transforms(:,:,i + 1) = transforms(:,:,i) * trotz(L.theta) * transl(0,0, q(i)) * transl(L.a,0,0) * trotx(L.alpha);
                else
                    % Use the theta value from the robot model
                    theta = L.theta;
                    if isnumeric(theta)
                        joint_angle = theta + q(i);
                    else
                        joint_angle = q(i); % If theta is symbolic (e.g., 'q1'), use the joint angle directly
                    end
                    transforms(:,:,i + 1) = transforms(:,:, i) * trotz(joint_angle + L.offset) * transl(0,0, L.d) * transl(L.a,0,0) * trotx(L.alpha);
                end
                
            end
        end

        function [collision,swordCollide] = CheckCollision(app, robot1, robot2, sword_vertices)
            collision = false;
            swordCollide = false;
            poses1 = app.GetLinkPoses(robot1.model.getpos, robot1.model);
            poses2 = app.GetLinkPoses(robot2.model.getpos, robot2.model);
            
            [point1, radiis, ellipsoidCenters] = app.UpdateEllipsoid(robot1, poses1);
            [point2, radiis, ellipsoidCenters] = app.UpdateEllipsoid(robot2, poses2);
            
            sizeRadii=0.05;
            
            % check collision between two robot
            for i = 2: size(poses1,3)
                for k = 2: size(point2,2)
                    distanceFromXYZ = robot1.model.links(i-1).a';
                    radii = [distanceFromXYZ/1.5, sizeRadii, sizeRadii];
        
                    cubePointsAndOnes = [inv(poses1(:,:,i)) * [point2{k},ones(size(point2{k},1),1)]']';
                    updatedCubePoints = cubePointsAndOnes(:,1:3);
                    % base = robot1.model.base.T;
                    base = poses1(:,:,2);
                    algebraicDist = app.GetAlgebraicDist(updatedCubePoints, base(1:3,4)', radii);
                    pointsInside = find(algebraicDist < 1);
                    if length(pointsInside) > 5
                        disp(['Base on robot1 There are ', num2str(size(pointsInside,1)),' tr inside the ',num2str(i), ' points inside the ',num2str(k), ' th ellipsoid']);
                        collision = true;
                        return;
                    end

                    swordCollide = app.checkObjectsCollision(sword_vertices{1}, point2{k});
                    if swordCollide
                        app.WinLabel.Text = ["Player 1 wins!"];
                        disp('Sword 1 attacked robot 2')
                        return
                    end
                end
            end
            for i = 2: size(poses2,3)
                for k = 2: size(point1,2)
                    swordCollide = app.checkObjectsCollision(sword_vertices{2}, point1{k});
                    if swordCollide
                        app.WinLabel.Text = ["Player 2 wins!"];
                        disp('Sword 2 attacked robot 1')
                        return
                    end
                end
            end
            app.WinLabel.Text = ["Draw."];
            
        end

        %% function 
        function algebraicDist = GetAlgebraicDist(app, points, centerPoint, radii)
            try
                algebraicDist = ((points(:,1)-centerPoint(1))/radii(1)).^2 ...
                            + ((points(:,2)-centerPoint(2))/radii(2)).^2 ...
                            + ((points(:,3)-centerPoint(3))/radii(3)).^2;
            catch
                return
            end
        end

        function CalculateAllPossiblePositionRobot1(app,robot)
            stepRads = deg2rad(30);
            qlim = robot.model.qlim;
            % Don't need to worry about joint 7
            pointCloudeSize = prod(floor((qlim(1:6,2)-qlim(1:6,1))/stepRads + 1));
            pointCloud = zeros(pointCloudeSize,3);
            counter = 1;
            tic

            for q1 = qlim(1,1):stepRads:qlim(1,2)
                for q2 = qlim(2,1):stepRads:qlim(2,2)
                    for q3 = qlim(3,1):stepRads:qlim(3,2)
                        for q4 = qlim(4,1):stepRads:qlim(4,2)
                            for q5 = qlim(5,1):stepRads:qlim(5,2)
                                % Don't need to worry about joint 7, just assume it=0
                                for q6 = qlim(6,1):stepRads:qlim(6,2)
                                    q7 = 0;
                                    q = [q1,q2,q3,q4,q5,q6,q7];
                                    tr = robot.model.fkine(q);     
                                    translationVector = tr.t;
                                    pointCloud(counter,:) = translationVector;
                                    counter = counter + 1; 
                                    if mod(counter/pointCloudeSize * 100,1) == 0
                                        display(['After ',num2str(toc),' seconds, completed ',num2str(counter/pointCloudeSize * 100),'% of poses']);
                                    end
                                end
                            end
                        end
                    end
                end
            end
            
            % 2.6 Create a 3D model showing where the end effector can be over all these samples.  
            hold on;
            plot3(pointCloud(:,1),pointCloud(:,2),pointCloud(:,3),'r.');
            %% Store all possible position of the robot
            data = pointCloud;
            filename = 'PositionUR5CanDo.txt';  % Specify the file name
            % Call the function to write data to the file
            writeToTextFile(filename, data);

            %% function writeToTextFile
            function writeToTextFile(filename, data)
                % Check if the file exists
                if ~exist(filename, 'file')
                    % Create a new file if it doesn't exist
                    try
                        fileID = fopen(filename, 'w');
                        fclose(fileID);
                    catch
                        error('Failed to create the file.');
                    end
                end
            
                % Open the file for writing
                fileID = fopen(filename, 'w');
            
                % Write the data to the file
                fprintf(fileID, '%d %d %d\n', data');
            
                % Close the file
                fclose(fileID);
            end
            
        end

        function CalculateAllPossiblePositionRobot2(app,robot)
            stepRads = deg2rad(30);
            qlim = robot.model.qlim;
            % Don't need to worry about joint 7
            pointCloudeSize = prod(floor((qlim(1:6,2)-qlim(1:6,1))/stepRads + 1));
            pointCloud = zeros(pointCloudeSize,3);
            counter = 1;
            tic

            for q1 = qlim(1,1):stepRads:qlim(1,2)
                for q2 = qlim(2,1):stepRads:qlim(2,2)
                    for q3 = qlim(3,1):stepRads:qlim(3,2)
                        for q4 = qlim(4,1):stepRads:qlim(4,2)
                            for q5 = qlim(5,1):stepRads:qlim(5,2)
                                % Don't need to worry about joint 7, just assume it=0
                                q6 = 0;
                                q = [q1,q2,q3,q4,q5,q6];
                                tr = robot.model.fkine(q);     
                                translationVector = tr.t;
                                pointCloud(counter,:) = translationVector;
                                counter = counter + 1; 
                                if mod(counter/pointCloudeSize * 100,1) == 0
                                    display(['After ',num2str(toc),' seconds, completed ',num2str(counter/pointCloudeSize * 100),'% of poses']);
                                end
                                
                            end
                        end
                    end
                end
            end
            
            % 2.6 Create a 3D model showing where the end effector can be over all these samples.  
            hold on;
            plot3(pointCloud(:,1),pointCloud(:,2),pointCloud(:,3),'r.');
            %% Store all possible position of the robot
            data = pointCloud;
            filename = 'FanucM20.txt';  % Specify the file name
            % Call the function to write data to the file
            writeToTextFile(filename, data);

            %% function writeToTextFile
            function writeToTextFile(filename, data)
                % Check if the file exists
                if ~exist(filename, 'file')
                    % Create a new file if it doesn't exist
                    try
                        fileID = fopen(filename, 'w');
                        fclose(fileID);
                    catch
                        error('Failed to create the file.');
                    end
                end
            
                % Open the file for writing
                fileID = fopen(filename, 'w');
            
                % Write the data to the file
                fprintf(fileID, '%d %d %d\n', data');
            
                % Close the file
                fclose(fileID);
            end
            
        end

        function checkValueInFile(filename, targetValue)
            % Read data from the file
            try
                fileData = importdata(filename);  % Import data from the file
                data = fileData;  % Extract data from the imported data structure
            catch
                error('Failed to read data from the file.');
            end
        
            % Assuming you have defined targetValue and data as mentioned
            found = false;  % Initialize a flag to check if the value is found
            
            % Define the number of decimal places to round to
            decimalPlaces = 3;
            
            % Round the targetValue to the specified number of decimal places
            roundedTargetValue = round(targetValue, decimalPlaces);
            
            % Check if targetValue is present in data (rounded to the same decimal places)
            for i = 1:size(data, 1)
                roundedData = round(data(i, :), decimalPlaces);
                if isequal(roundedData, roundedTargetValue)
                    found = true;
                    break;  % Exit the loop if the value is found
                end
            end
            
            % Print a message based on whether the value is found
            if found
                fprintf('Value [%f %f %f] is present in the file.\n', targetValue);
            else
                fprintf('Value [%f %f %f] is not found in the file.\n', targetValue);
            end
        end

        function DisplayAllPossiblePositionAndWorkspace(app,filename)
            % Read data from the file
            try
                fileData = importdata(filename);  % Import data from the file
                data = fileData;  % Extract data from the imported data structure
            catch
                error('Failed to read data from the file.');
            end
        
            % Display collected data points
            % figure;
            check = plot3(data(:,1),data(:,2),data(:,3),'r.');
            display('Press enter to delete this plot spaces')
            pause();
            try delete(check); end
        end

        %% EXTRA FUNCTIONS

        function resetRobot(app)
            app.WinLabel.Text = ["Who will win?"];
            Pose = app.Preparepose;
            [app.gripper, ~] = MoveRobotToLocation(app, app.robots, Pose, app.swords, app.sword_vertices, app.steps, false);
        end

        function [intersectionPoint,check] = LinePlaneIntersection(planeNormal,pointOnPlane,point1OnLine,point2OnLine)
            intersectionPoint = [0 0 0];
            u = point2OnLine - point1OnLine;
            w = point1OnLine - pointOnPlane;
            D = dot(planeNormal,u);
            N = -dot(planeNormal,w);
            check = 0; %#ok<NASGU>
            if abs(D) < 10^-7        % The segment is parallel to plane
                if N == 0           % The segment lies in plane
                    check = 2
                    return
                else
                    check = 0       %no intersection
                    return
                end
            end
            
            %compute the intersection parameter
            sI = N / D;
            intersectionPoint = point1OnLine + sI.*u;
            
            if (sI < 0 || sI > 1)
                check= 3          %The intersection point  lies outside the segment, so there is no intersection
            else
                check=1
            end
        end

        %% Move end effector grippers
        function moveEndEffector(app, base_pos, grip_1, grip_2, end_effector_open)
            grip_1.base = base_pos*trotx(pi/2)*transl(0.035,0,0);
            grip_2.base = base_pos*trotx(pi/2)*transl(-0.035,0,0);
            % grip_1.base = grip_1_plot;
            % grip_2.base = grip_2_plot;

            if end_effector_open == true
                q = [145*pi/180];
            else
                q = [pi/2];
            end
         
            grip_1.animate(pi-q);                  % Plot the robot
            grip_2.animate(q);
        end
         
        %% Open end effector grippers
        function openEndEffector(app, grip_1, grip_2)
            for i = (pi/2):(pi/70):(145*pi/180)
                grip_1.animate(pi-i);                  % Plot the robot
                grip_2.animate(i);
            end
        end
         
        %% Close end effector grippers


        function closeEndEffector(app, grip_1, grip_2)
            for i = (145*pi/180):(-pi/70):(pi/2)
                grip_1.animate(pi-i);                  % Plot the robot
                grip_2.animate(i);
            end
        end
        
        function [grip_1, grip_2] = makeEndEffector(app, name)
            % Define DH parameters for gripper 1
            L1 = Link('d',0, 'a', 0.04, 'alpha', 0, 'qlim',[0 pi/2]);
            % % Define DH parameters for gripper 2
            L2 = Link('d',0, 'a', 0.04, 'alpha', 0, 'qlim',[pi/2 pi]);
            
            grip_1 = SerialLink([L1], 'name', 'TwoGripperEndEffector');
            grip_2 = SerialLink([L2], 'name', 'TwoGripperEnd');
            
            q = [pi/2];
         
            grip_1.plot(q);                  % Plot the robot
            grip_2.plot(q);
        end

        % Gripper //////////////////////////////////////////
        function gripper = CreateGrip(app, gripper,robot)
            pose = robot.model.fkine(robot.model.getpos).T;
            gripper(1) = app.DeleteObject(gripper(1));
            gripper(2) = app.DeleteObject(gripper(2));
            gripper(1) = PlaceObject('model3D/Newhand.ply');
            gripper(2) = PlaceObject('model3D/Newhand2.ply');
            gripper(3) = 45;
            % need to set z of end-effector to have offset in z about 0.0859
            app.RotateObjectgGripper(gripper(1), [0,0,0], pose * trotz(180, 'deg') * trotx(-gripper(3), 'deg'));
            app.RotateObjectgGripper(gripper(2), [0,0,0], pose * trotz(180, 'deg') * trotx(gripper(3), 'deg'));
        end
        function gripper = SetupGrip(app, gripper,robot)
            pose = robot.model.fkine(robot.model.getpos).T;
            gripper(1) = app.DeleteObject(gripper(1));
            gripper(2) = app.DeleteObject(gripper(2));
            gripper(1) = PlaceObject('model3D/Newhand.ply');
            gripper(2) = PlaceObject('model3D/Newhand2.ply');
            % need to set z of end-effector to have offset in z about 0.0859
            app.RotateObjectgGripper(gripper(1), [0,0,0], pose * trotz(180, 'deg') * trotx(-gripper(3), 'deg'));
            app.RotateObjectgGripper(gripper(2), [0,0,0], pose * trotz(180, 'deg') * trotx(gripper(3), 'deg'));
        end
        function gripper = DeleteGrip(app, gripper)
            gripper(1) = app.DeleteObject(gripper(1));
            gripper(2) = app.DeleteObject(gripper(2));
        end
        function obj = DeleteObject(app, obj)
            try
                delete(obj);
            end
        end

        function RotateObjectgGripper (app, gripper,location, degree)
            % Define the rotation angle in degrees (e.g., 180 degrees)
            rotationAngleDegrees = degree;
            % Create a homogeneous transformation matrix for rotation
            rotationMatrix = transl(location) * rotationAngleDegrees;            
            % Get the object's vertices
            vertices = get(gripper, 'Vertices');
            % Apply the rotation transformation to the object's vertices
            transformedVertices = (rotationMatrix * [vertices, ones(size(vertices, 1), 1)]')';
            % Update the object's vertices with the rotated vertices
            set(gripper, 'Vertices', transformedVertices(:, 1:3));
        end
       
        function gripper = GridGripping(app, gripper, robot, setGrip)   % true if grip, false ungrip
            for i = 1:30
                if setGrip
                    gripper(3) = gripper(3) - 1;
                else
                    gripper(3) = gripper(3) + 1;
                end
                pose = robot.model.fkine(robot.model.getpos).T;
                % Degree = Degree - 1;
                gripper(1) = app.DeleteObject(gripper(1));
                gripper(2) = app.DeleteObject(gripper(2));
                gripper(1) = PlaceObject('model3D/Newhand.ply');
                gripper(2) = PlaceObject('model3D/Newhand2.ply');
                app.RotateObjectgGripper(gripper(1), [0,0,0], pose * trotz(180, 'deg') * trotx(-gripper(3), 'deg'))
                app.RotateObjectgGripper(gripper(2), [0,0,0], pose * trotz(180, 'deg') * trotx(gripper(3), 'deg'))
                pause(0.001);
            end 
        end      
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clf;
            clc;
            hold on
            axis([-2 2 -2 2 0 4]);
            camlight;
            
            %% Setup Swords
            [app.swords{1}, app.sword_vertices{1}, app.swords{2}, app.sword_vertices{2}] = app.setupSwords(app.swordfile{1}, app.swordfile{2}, app.swordStartLoc{1}, app.swordStartLoc{2});
            
            %% Setup Robots
            app.robots{1} = app.SetupRobot(app.baseRobot{1}, false);
            app.robots{2} = app.SetupRobot(app.baseRobot{2}, true);
            %% Setup Gripper
            if app.TurnOnGripper
                app.gripper(1,:) = app.CreateGrip(app.gripper(1,:),app.robots{1});
                app.gripper(2,:) = app.CreateGrip(app.gripper(2,:),app.robots{2});
            end
            %% Setup Ellipsoid
            app.robots = app.SetupEllipsoid(app.robots);            
            %% Setup Environment
            app.ObjectInTheScene = app.SetupEnvironment();
            [objectVertices] = app.UpdateObjectsVertices(app.ObjectInTheScene);
            %% plot bounding box
            % app.plotBoundingBox(objectVertices{1});
            % app.plotBoundingBox(objectVertices{2});
            %% Set home pose 
            [app.gripper, ~] = app.pickupSwordsStep(app.robots, app.swordStartLoc, app.swords, app.sword_vertices, app.steps);
            app.pose = cell(1,2);
            app.pose{1} = app.robots{1}.model.fkine(app.robots{1}.homeQ).T;
            app.pose{2} = app.robots{2}.model.fkine(app.robots{2}.homeQ).T;
            [app.gripper, ~] = app.MoveRobotToLocation(app.robots, app.Preparepose, app.swords, app.sword_vertices, app.steps);
            
            app.WinLabel.Text = ["Who will win?"];

            
        end

        % Button pushed function: Player2_pos2
        function Player2_pos2Pushed(app, event)
            app.robot_2_q = [app.Fanuc_poses(2,:)];
            app.NopositionselectedLabel_2.Text = ["Position 2 selected."];
        end

        % Button pushed function: Player1_pos1
        function Player1_pos1ButtonPushed(app, event)
            app.robot_1_q = [app.UR5_poses(1,:)];
            app.NopositionselectedLabel.Text = ["Position 1 selected."];
        end

        % Button pushed function: FightButton_2
        function FightButton_2Pushed(app, event)
            if strcmp(app.NopositionselectedLabel.Text, "No position selected.") || strcmp(app.NopositionselectedLabel_2.Text, "No position selected.")
                app.WinLabel.Text = ["Please select position."];
                return
            else
                Pose = {app.robot_1_q, app.robot_2_q};
                [app.gripper, ~] = app.MoveRobotToLocation(app.robots, Pose, app.swords, app.sword_vertices, app.steps);
            end            
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            resetRobot(app);
        end

        % Button pushed function: Player1_pos2
        function Player1_pos2ButtonPushed(app, event)
            app.robot_1_q = [app.UR5_poses(2,:)];
            app.NopositionselectedLabel.Text = ["Position 2 selected."];
        end

        % Button pushed function: Player1_pos3
        function Player1_pos3ButtonPushed(app, event)
            app.robot_1_q = [app.UR5_poses(3,:)];
            app.NopositionselectedLabel.Text = ["Position 3 selected."];
        end

        % Button pushed function: Player1_pos4
        function Player1_pos4ButtonPushed(app, event)
            app.robot_1_q = [app.UR5_poses(4,:)];
            app.NopositionselectedLabel.Text = ["Position 4 selected."];
        end

        % Button pushed function: Player2_pos1
        function Player2_pos1ButtonPushed(app, event)
            app.robot_2_q = [app.Fanuc_poses(1,:)];
            app.NopositionselectedLabel_2.Text = ["Position 1 selected."];
        end

        % Button pushed function: Player2_pos3
        function Player2_pos3ButtonPushed(app, event)
            app.robot_2_q = [app.Fanuc_poses(3,:)];
            app.NopositionselectedLabel_2.Text = ["Position 3 selected."];
        end

        % Button pushed function: Player2_pos4
        function Player2_pos4ButtonPushed(app, event)
            app.robot_2_q = [app.Fanuc_poses(4,:)];
            app.NopositionselectedLabel_2.Text = ["Position 4 selected."];
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global reset
            popUp=uifigure;
            popUp.Position(3:4) = [350 200];

            %Message, Title
            selection = uiconfirm(popUp,"Warning: Emergency Stop","Program Paused","Options",["Resume", "Reset"],"DefaultOption", 1,"Icon","error");
            switch selection
                case "Resume"
                    close(popUp)
                case "Reset"
                    reset=true;
                    resetRobot(app);
                    close(popUp)
            end
        end

        % Value changing function: J1Slider
        function J1SliderValueChanging(app, event)
            changingValue = event.Value;
            display(changingValue);
            Pose = {[changingValue,0,0,0,0,0,0], [-pi/2 pi/2 1.3 0 0 0.26]};
            [app.gripper, ~] = app.MoveRobotToLocation(app.robots, Pose, app.swords, app.sword_vertices, app.steps);
            pause(0.5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sword fighting')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = 0;
            app.UIAxes.XTickLabel = {'0'};
            app.UIAxes.YTick = 0;
            app.UIAxes.YTickLabel = {'0'};
            app.UIAxes.ZTick = 0;
            app.Player1_pos1 = uibutton(app.UIFigure, 'push');
            app.Player1_pos1.ButtonPushedFcn = createCallbackFcn(app, @Player1_pos1ButtonPushed, true);
            app.Player1_pos1.Text = 'Position 1';
            app.Player1_pos2 = uibutton(app.UIFigure, 'push');
            app.Player1_pos2.ButtonPushedFcn = createCallbackFcn(app, @Player1_pos2ButtonPushed, true);
            app.Player1_pos2.Text = 'Position 2';
            app.Player1_pos3 = uibutton(app.UIFigure, 'push');
            app.Player1_pos3.ButtonPushedFcn = createCallbackFcn(app, @Player1_pos3ButtonPushed, true);
            app.Player1_pos3.Text = 'Position 3';
            app.Player2_pos1 = uibutton(app.UIFigure, 'push');
            app.Player2_pos1.ButtonPushedFcn = createCallbackFcn(app, @Player2_pos1ButtonPushed, true);
            app.Player2_pos1.Text = 'Position 1';
            app.Player2_pos2 = uibutton(app.UIFigure, 'push');
            app.Player2_pos2.ButtonPushedFcn = createCallbackFcn(app, @Player2_pos2Pushed, true);
            app.Player2_pos2.Text = 'Position 2';
            app.Player2_pos3 = uibutton(app.UIFigure, 'push');
            app.Player2_pos3.ButtonPushedFcn = createCallbackFcn(app, @Player2_pos3ButtonPushed, true);
            app.Player2_pos3.Text = 'Position 3';
            app.Player2TextAreaLabel = uilabel(app.UIFigure);
            app.Player2TextAreaLabel.Text = 'Player 2';
            app.Player1TextAreaLabel = uilabel(app.UIFigure);
            app.Player1TextAreaLabel.Text = 'Player 1';
            app.ResetButton_2 = uibutton(app.UIFigure, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.WinLabel = uilabel(app.UIFigure);
            app.WinLabel.Text = 'Who will win?';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Player2_pos4 = uibutton(app.UIFigure, 'push');
            app.Player2_pos4.ButtonPushedFcn = createCallbackFcn(app, @Player2_pos4ButtonPushed, true);
            app.Player2_pos4.Text = 'Position 4';
            app.Player1_pos4 = uibutton(app.UIFigure, 'push');
            app.Player1_pos4.ButtonPushedFcn = createCallbackFcn(app, @Player1_pos4ButtonPushed, true);
            app.Player1_pos4.Text = 'Position 4';
            app.FightButton_2 = uibutton(app.UIFigure, 'push');
            app.FightButton_2.ButtonPushedFcn = createCallbackFcn(app, @FightButton_2Pushed, true);
            app.FightButton_2.Text = 'Fight!';
            app.NopositionselectedLabel = uilabel(app.UIFigure);
            app.NopositionselectedLabel.Text = 'No position selected.';
            app.NopositionselectedLabel_2 = uilabel(app.UIFigure);
            app.NopositionselectedLabel_2.Text = 'No position selected.';
            app.Player1TextAreaLabel_2 = uilabel(app.UIFigure);
            app.Player1TextAreaLabel_2.Text = 'Welcome to sword fighting robots!';
            app.J2SliderLabel = uilabel(app.UIFigure);
            app.J2SliderLabel.Text = 'J2';
            app.J2Slider = uislider(app.UIFigure);
            app.J2Slider.MajorTicks = [];
            app.J2Slider.MinorTicks = [];
            app.J3SliderLabel = uilabel(app.UIFigure);
            app.J3SliderLabel.Text = 'J3';
            app.J3Slider = uislider(app.UIFigure);
            app.J3Slider.MajorTicks = [];
            app.J3Slider.MinorTicks = [];
            app.J4SliderLabel = uilabel(app.UIFigure);
            app.J4SliderLabel.Text = 'J4';
            app.J4Slider = uislider(app.UIFigure);
            app.J4Slider.MajorTicks = [];
            app.J4Slider.MinorTicks = [];
            app.J5SliderLabel = uilabel(app.UIFigure);
            app.J5SliderLabel.Text = 'J5';
            app.J5Slider = uislider(app.UIFigure);
            app.J5Slider.MajorTicks = [];
            app.J5Slider.MinorTicks = [];
            app.J6SliderLabel = uilabel(app.UIFigure);
            app.J6SliderLabel.Text = 'J6';
            app.J6Slider = uislider(app.UIFigure);
            app.J6Slider.MajorTicks = [];
            app.J6Slider.MinorTicks = [];
            app.J7SliderLabel = uilabel(app.UIFigure);
            app.J7SliderLabel.Text = 'J7';
            app.J7Slider = uislider(app.UIFigure);
            app.J7Slider.MajorTicks = [];
            app.J7Slider.MinorTicks = [];
            app.J1Slider_2Label = uilabel(app.UIFigure);
            app.J1Slider_2Label.Text = 'J1';
            app.J1Slider_2 = uislider(app.UIFigure);
            app.J1Slider_2.MajorTicks = [];
            app.J1Slider_2.MinorTicks = [];
            app.J2Slider_2Label = uilabel(app.UIFigure);
            app.J2Slider_2Label.Text = 'J2';
            app.J2Slider_2 = uislider(app.UIFigure);
            app.J2Slider_2.MajorTicks = [];
            app.J2Slider_2.MinorTicks = [];
            app.J3Slider_2Label = uilabel(app.UIFigure);
            app.J3Slider_2Label.Text = 'J3';
            app.J3Slider_2 = uislider(app.UIFigure);
            app.J3Slider_2.MajorTicks = [];
            app.J3Slider_2.MinorTicks = [];
            app.J4Slider_2Label = uilabel(app.UIFigure);
            app.J4Slider_2Label.Text = 'J4';
            app.J4Slider_2 = uislider(app.UIFigure);
            app.J4Slider_2.MajorTicks = [];
            app.J4Slider_2.MinorTicks = [];
            app.J5Slider_2Label = uilabel(app.UIFigure);
            app.J5Slider_2Label.Text = 'J5';
            app.J5Slider_2 = uislider(app.UIFigure);
            app.J5Slider_2.MajorTicks = [];
            app.J5Slider_2.MinorTicks = [];
            app.J6Slider_2Label = uilabel(app.UIFigure);
            app.J6Slider_2Label.Text = 'J6';
            app.J6Slider_2 = uislider(app.UIFigure);
            app.J6Slider_2.MajorTicks = [];
            app.J6Slider_2.MinorTicks = [];
            app.J1SliderLabel = uilabel(app.UIFigure);
            app.J1SliderLabel.Text = 'J1';
            app.J1Slider = uislider(app.UIFigure);
            app.J1Slider.Limits = [-0.8 -0.01];
            app.J1Slider.MajorTicks = [];
            app.J1Slider.MajorTickLabels = {''};
            app.J1Slider.ValueChangingFcn = createCallbackFcn(app, @J1SliderValueChanging, true);
            app.J1Slider.MinorTicks = [];
            app.J1Slider.Value = -0.01;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = swordFighting_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end